classdef LPC_Vocoder_Interim_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AnalysisPanel           matlab.ui.container.Panel
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        InputPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        ClickhereButtonGroup    matlab.ui.container.ButtonGroup
        RecordButton            matlab.ui.control.Button
        PlayButton              matlab.ui.control.Button
        DurationsTextAreaLabel  matlab.ui.control.Label
        DurationsTextArea       matlab.ui.control.TextArea
        ORLabel                 matlab.ui.control.Label
        ClickhereButtonGroup_2  matlab.ui.container.ButtonGroup
        LoadAudioButton         matlab.ui.control.Button
        PlayButton_2            matlab.ui.control.Button
        UIAxes_2                matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            
            audioObject = audiorecorder;     
            Fs = audioObject.SampleRate;
            duration = str2double(app.DurationsTextArea.Value{1});
            msgbox('Recording');
            recordblocking(audioObject, duration);
            msgbox('Done');   
            y = getaudiodata(audioObject);
            plot(app.UIAxes, (1/Fs)*(0:length(y)-1), y);
            %plot(app.UIAxes2, spectrogram(y));
            assignin('base', 'audioObject', audioObject);
            
                [s,f,t]=spectrogram(y,hamming(240),120,audioObject.SampleRate);
                mags=s.*conj(s);
                image(app.UIAxes2,f, t, -10.*log10(mags/max(max(mags))));
                set(app.UIAxes2, 'YDir','normal');
                ylim(app.UIAxes2,[t(1) t(end)]);
                xlim(app.UIAxes2,[f(1) f(end)]);
                %colorbar(app.UIAxes2);
                
                reconstructed = y;
                [s,f,t]=spectrogram(reconstructed,hamming(240),120,audioObject.SampleRate);
                mags=s.*conj(s);
                image(app.UIAxes3,f, t, -10.*log10(mags/max(max(mags))));
                set(app.UIAxes3, 'YDir','normal');
                ylim(app.UIAxes3,[t(1) t(end)]);
                xlim(app.UIAxes3,[f(1) f(end)]);
                    
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            audioObject = evalin('base', 'audioObject');
            y = getaudiodata(audioObject);
            Fs = audioObject.SampleRate;
            sound(y, Fs);
         
        end

        % Callback function
        function SwitchValueChanged(app, event)
            
            audioObject = evalin('base', 'audioObject');
            y = getaudiodata(audioObject);
            %Fs = audioObject.SampleRate;
            value = app.Switch.Value;
            if(value)
                plot(app.UIAxes2, spectrogram(y));
            else
                x = lpc(y);
                plot(app.UIAxes2, spectrogram(x));
            end
                
        end

        % Callback function
        function UpdateButtonValueChanged(app, event)
                                                                
        end

        % Callback function
        function UpdateButtonPushed(app, event)
         
        end

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
            
            %[FileName, PathName] = uigetfile({'*.wav; *.aif'}, 'Select audio file');
            FileName = 'bernie_mike_14700.wav';

%Fs = audioObject.SampleRate;
global sout;

            duration = str2double(app.DurationsTextArea.Value{1});
    
            %y = getaudiodata(audioObject);
            [y, Fs] = audioread(FileName);
            plot(app.UIAxes, (1/Fs)*(0:length(y)-1), y);
            %plot(app.UIAxes2, spectrogram(y));
            %assignin('base', 'audioObject', audioObject);
            
            %disp(y);
            
            plot(app.UIAxes_2, (1/16000)*(0:length(sout)-1), sout);
            
                [s,f,t]=spectrogram(y(:,1),hamming(240),120,Fs);
                mags=s.*conj(s);
                image(app.UIAxes2,f, t, -10.*log10(mags/max(max(mags))));
                set(app.UIAxes2, 'YDir','normal');
                ylim(app.UIAxes2,[t(1) t(end)]);
                xlim(app.UIAxes2,[f(1) f(end)]);
                %colorbar(app.UIAxes2);
                
                
                reconstructed = sout;
                [s,f,t]=spectrogram(reconstructed,hamming(240), 120, 16000);
                
                mags=real(s.*conj(s));
                %disp(mags);
                image(app.UIAxes3,f, t, -10.*log10(mags/max(max(mags))));
                set(app.UIAxes3, 'YDir','normal');
                ylim(app.UIAxes3,[t(1) t(end)]);
                xlim(app.UIAxes3,[f(1) f(end)]);


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.UIAxes2 = uiaxes(app.AnalysisPanel);
            title(app.UIAxes2, 'Original audio spectrogram')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.AnalysisPanel);
            title(app.UIAxes3, 'Reconstructed audio spectrogram')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.UIAxes = uiaxes(app.InputPanel);
            title(app.UIAxes, 'Recorded Audio Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ClickhereButtonGroup = uibuttongroup(app.InputPanel);
            app.ClickhereButtonGroup.Title = 'Click here';
            app.RecordButton = uibutton(app.ClickhereButtonGroup, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.PlayButton = uibutton(app.ClickhereButtonGroup, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.DurationsTextAreaLabel = uilabel(app.ClickhereButtonGroup);
            app.DurationsTextAreaLabel.Text = 'Duration (s)';
            app.DurationsTextArea = uitextarea(app.ClickhereButtonGroup);
            app.DurationsTextArea.Value = {'5'};
            app.ORLabel = uilabel(app.InputPanel);
            app.ORLabel.Text = 'OR';
            app.ClickhereButtonGroup_2 = uibuttongroup(app.InputPanel);
            app.ClickhereButtonGroup_2.Title = 'Click here';
            app.LoadAudioButton = uibutton(app.ClickhereButtonGroup_2, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Text = 'Load Audio';
            app.PlayButton_2 = uibutton(app.ClickhereButtonGroup_2, 'push');
            app.PlayButton_2.Text = 'Play';
            app.UIAxes_2 = uiaxes(app.InputPanel);
            title(app.UIAxes_2, 'Reconstructed Audio Signal')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = LPC_Vocoder_Interim_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end